classdef Pedal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        DecayKnob         matlab.ui.control.Knob
        MixKnob           matlab.ui.control.Knob
        WetnessKnobLabel  matlab.ui.control.Label
        DecayKnobLabel    matlab.ui.control.Label
        Label             matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        WETNESSLabel      matlab.ui.control.Label
        WetnessKnob       matlab.ui.control.Knob
        reverbLampa       matlab.ui.control.Lamp
        ReverbLabel       matlab.ui.control.Label
        reverbKnapp       matlab.ui.control.Button
        DISTKnobLabel     matlab.ui.control.Label
        DISTKnob          matlab.ui.control.Knob
        Image             matlab.ui.control.Image
    end

    
    properties (Access = public)
    WetDryMix = 0
    DecayFactor = 1
    Diffusion = 0.5
    fileReader;
    deviceWriter;
    reverb;
    kDist=0.9;


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.fileReader = dsp.AudioFileReader('gitarr.wav','SamplesPerFrame',1024);
        app.deviceWriter = audioDeviceWriter('SampleRate', app.fileReader.SampleRate);
        app.reverb = reverberator('WetDryMix', app.WetDryMix,'DecayFactor', app.DecayFactor, 'Diffusion', app.Diffusion);
        app.Dist.BorderType
        
        end

        % Value changing function: MixKnob
        function MixKnobValueChanging(app, event)
            mixValue = event.Value;
            app.WetDryMix = mixValue;
            app.reverb = reverberator('WetDryMix', app.WetDryMix,'DecayFactor', app.DecayFactor, 'Diffusion', app.Diffusion);
            
        end

        % Callback function
        function WetnessKnobValueChanging(app, event)
            wetValue = event.Value;
            app.WetDryMix = wetValue;
            app.reverb = reverberator('WetDryMix', app.WetDryMix,'DecayFactor', app.DecayFactor, 'Diffusion', app.Diffusion);
        end

        % Value changing function: DecayKnob
        function DecayKnobValueChanging(app, event)
            decayValue = event.Value;
            app.DecayFactor = decayValue;
            app.reverb = reverberator('WetDryMix', app.WetDryMix,'DecayFactor', app.DecayFactor, 'Diffusion', app.Diffusion);
        end

        % Callback function
        function PlayButtonPushed(app, event)
        while ~isDone(app.fileReader)
            audio = app.fileReader();
            drawnow 
            audioWithDist = (1+app.kDist)*(audio)./(1+app.kDist*abs(audio));
            audioWithReverb = app.reverb(audioWithDist);
            app.deviceWriter(audioWithReverb);
        end
        release(app.fileReader);
        end

        % Value changing function: DISTKnob
        function DISTKnobValueChanging(app, event)
            distValue = event.Value;
            app.kDist = 2*distValue/(1-distValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DecayKnob = uiknob(app.UIFigure, 'continuous');
            app.DecayKnob.Limits = [0 1];
            app.DecayKnob.ValueChangingFcn = createCallbackFcn(app, @DecayKnobValueChanging, true);
            app.DecayKnob.Value = 1;
            app.MixKnob = uiknob(app.UIFigure, 'continuous');
            app.MixKnob.Limits = [0 1];
            app.MixKnob.ValueChangingFcn = createCallbackFcn(app, @MixKnobValueChanging, true);
            app.WetnessKnobLabel = uilabel(app.UIFigure);
            app.WetnessKnobLabel.Text = 'Wetness';
            app.DecayKnobLabel = uilabel(app.UIFigure);
            app.DecayKnobLabel.Text = 'Decay';
            app.DISTKnobLabel = uilabel(app.UIFigure);
            app.DISTKnobLabel.Text = {'DIST'; ''};
            app.DISTKnob = uiknob(app.UIFigure, 'continuous');
            app.DISTKnob.Limits = [0 1];
            app.DISTKnob.MajorTicks = [0 1 5];
            app.DISTKnob.ValueChangingFcn = createCallbackFcn(app, @DISTKnobValueChanging, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'reverb.png';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.reverbLampa = uilamp(app.UIFigure);
            app.reverbKnapp = uibutton(app.UIFigure, 'push');
            app.reverbKnapp.Visible = 'off';
            app.reverbKnapp.Text = '';
            app.ReverbLabel = uilabel(app.UIFigure);
            app.ReverbLabel.Text = 'Reverb';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {''; ''; ''};
            app.WetnessKnob = uiknob(app.UIFigure, 'continuous');
            app.WetnessKnob.MajorTicks = [];
            app.WetnessKnob.MinorTicks = [];
            app.WETNESSLabel = uilabel(app.UIFigure);
            app.WETNESSLabel.Text = {'WETNESS'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pedal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end